classdef App_3DLocator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CustomcolorPanel                matlab.ui.container.Panel
        customColorApplyButton          matlab.ui.control.Button
        YellowEditField                 matlab.ui.control.EditField
        YellowEditFieldLabel            matlab.ui.control.Label
        CyanEditField                   matlab.ui.control.EditField
        CyanEditFieldLabel              matlab.ui.control.Label
        MagentaEditField                matlab.ui.control.EditField
        MagentaEditFieldLabel           matlab.ui.control.Label
        CustomtilePanel                 matlab.ui.container.Panel
        findCenterButton2               matlab.ui.control.Button
        whichDot                        matlab.ui.control.DropDown
        DotDropDownLabel                matlab.ui.control.Label
        Z_move                          matlab.ui.control.Slider
        ZSliderLabel_2                  matlab.ui.control.Label
        Y_move                          matlab.ui.control.Slider
        YSliderLabel_2                  matlab.ui.control.Label
        X_move                          matlab.ui.control.Slider
        XSlider_7Label_2                matlab.ui.control.Label
        tileCorrectionDropDown          matlab.ui.control.DropDown
        TileDropDown_2Label             matlab.ui.control.Label
        createtileButton                matlab.ui.control.Button
        Tree3                           matlab.ui.container.Tree
        TilesNode                       matlab.ui.container.TreeNode
        CutoffEditField                 matlab.ui.control.NumericEditField
        CutoffEditFieldLabel            matlab.ui.control.Label
        saveTileButton                  matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        Panel                           matlab.ui.container.Panel
        RecolorButton                   matlab.ui.control.Button
        WhichcolortouseButtonGroup      matlab.ui.container.ButtonGroup
        CustomcolorButton               matlab.ui.control.RadioButton
        SampledcolorsButton             matlab.ui.control.RadioButton
        SavedcolorsButton               matlab.ui.control.RadioButton
        findCenterButton1               matlab.ui.control.Button
        MinimalclustersizeSpinner       matlab.ui.control.Spinner
        MinimalclustersizeSpinnerLabel  matlab.ui.control.Label
        DotsizeSpinner                  matlab.ui.control.Spinner
        DotsizeSpinnerLabel             matlab.ui.control.Label
        PlotrecoloredButton             matlab.ui.control.Button
        PlotoriginalButton              matlab.ui.control.Button
        PrintButton                     matlab.ui.control.Button
        HardcutButton                   matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        UsethresholdButton              matlab.ui.control.Button
        ColorsimilaritythresholdLabel   matlab.ui.control.Label
        ThrSlider                       matlab.ui.control.Slider
        SaveButton                      matlab.ui.control.Button
        ZmaxLabel                       matlab.ui.control.NumericEditField
        YmaxLabel                       matlab.ui.control.NumericEditField
        ZminLabel                       matlab.ui.control.NumericEditField
        YminLabel                       matlab.ui.control.NumericEditField
        XmaxLabel                       matlab.ui.control.NumericEditField
        XminLabel                       matlab.ui.control.NumericEditField
        Zmin                            matlab.ui.control.Slider
        ZSliderLabel                    matlab.ui.control.Label
        Ymin                            matlab.ui.control.Slider
        YSliderLabel                    matlab.ui.control.Label
        Xmin                            matlab.ui.control.Slider
        XSlider_7Label                  matlab.ui.control.Label
        GetprofileButton                matlab.ui.control.Button
        UpperlimitLabel                 matlab.ui.control.Label
        LowerlimitLabel                 matlab.ui.control.Label
        Zmax                            matlab.ui.control.Slider
        Ymax                            matlab.ui.control.Slider
        Xmax                            matlab.ui.control.Slider
        LoadButton                      matlab.ui.control.Button
    end

    
    properties (Access = private)
        tmpPath = addpath(pwd,'\functions');
        originalCloud % Description
        transformedCloud
        cloudFileName
        firstPlot = true; %whether is the first time plotting or not
        axVals %contains the axis of the plot
        axVals2 %same as axVals but with repetitions
        profiles %dissimilarity with colorList of each color in transformedCloud
        windowAx
        matFile %name of file where variables are stored
        savedProps %properties found if matFile exist
        thr
        recolored %new list of colors with best match to profiles
        recoloredList %vector that stores which number of color was assigned to recolored
        centers % center of the base tile
        newLocation % Location of dots filtered and recolored
        newColor %color of dots filtered and recolored
        colorSample % Color in locations of clusters
        newColorList %Identified color
        cutoff = []; % cutoff criterion for clustering
        sampledColor = [];
        colorList = getColors(28); %list of three colors to use as sample for profile (28 refers to the saved values of magenta, blue and yellow).
        eClusters %structure where current clusters, their centers locations and colors are stored
        currentTiles %structure where tiles have been temporaly identified
        nClusters = 1;  % number of clusters to search
        cutIndx %indx of current cut
        indxWithProfile %indices that have run profile
        dotSize = 10; % size of dots for plotting
        clusterDot = 100; % Description
        tiles %structure of saved tiles. It contains: number, midpoint, 
        currentView %current point of view
        newTile %structure that contains location of custom tile
        newTilePlot %handle of custom tile
        distances % list of distances between clusters found
        maxTileNumber = 18; %maximum tile number possible (1-12 for the system, 13-17 for markers)
        anatomicalMarkers = {'Nz','Iz','Ar','Al','Cz','Nose'}; % List of anatomical markers
        newTileLines % coordinates to plot lines when creating the newTile. Currently is not used
        customColor % structure that contains custom color writen by user. It contains colorN which indicates the color number (1-3, magenta, blue, yellow) and color which contains the color in rgb (0-255)
        customDots %structure that contains custom dots to create a new tile
        
        
    end
    
    methods (Access = private)
         
        function updateSliders(app)
            %axVals = app.axVals;
            app.axVals2 = [app.axVals(1), app.axVals(2);app.axVals(1), app.axVals(2);app.axVals(3), app.axVals(4);app.axVals(3), app.axVals(4);app.axVals(5), app.axVals(6);app.axVals(5), app.axVals(6)];
            
            sliders = {app.Xmin,app.Xmax,app.Ymin,app.Ymax,app.Zmin,app.Zmax};
            
            for nSlider = 1:numel(sliders)
                currentSlider = sliders{nSlider};
                currentSlider.Limits = app.axVals2(nSlider,:);
                currentSlider.Value = app.axVals(nSlider);
                currentSlider.MajorTicks = app.axVals2(nSlider,:);
                %currentSlider.MinorTicks = linspace(axVals2(nSlider,1),axVals2(nSlider,2),100);
                labels = currentSlider.MajorTickLabels;
                for nLabel = 1:numel(labels)
                    val = sprintf('%.2f',str2double(labels{nLabel}));
                    currentSlider.MajorTickLabels{nLabel} = val;
                end
            end
        end
        
        function updateLabels(app,nLabel,changingValue)
            %sliderLabels = {app.XminLabel,app.XmaxLabel,app.YminLabel,app.YmaxLabel,app.ZminLabel,app.ZmaxLabel};
            %sliders = {app.Xmin,app.Xmax,app.Ymin,app.Ymax,app.Zmin,app.Zmax};
            %sliderLabels{nLabel}.Value = sliders{nLabel}.Value;
            updateAx(app,nLabel,changingValue);
        end
        
        function updateAx(app,nLabel,changingValue)
            newAx = app.axVals;
            newAx(nLabel) = changingValue;
            if newAx(1) < newAx(2) && newAx(3) < newAx(4) && newAx(5) < newAx(6)
                axis(app.windowAx,[newAx(1),newAx(2),newAx(3),newAx(4),newAx(5),newAx(6)]);
            end
            
            app.axVals = axis;
            app.axVals2 = [app.axVals(1), app.axVals(2);app.axVals(1), app.axVals(2);app.axVals(3), app.axVals(4);app.axVals(3), app.axVals(4);app.axVals(5), app.axVals(6);app.axVals(5), app.axVals(6)];
            %updateLimit(app,nLabel);
        end
        
        function updateLimit(app,nLabel)
            sliders = {app.Xmin,app.Xmax,app.Ymin,app.Ymax,app.Zmin,app.Zmax};
            if ismember(nLabel,[1,3,5]) %means that i'm working on 2,4,6
                nSlider = nLabel + 1;
                site = 2;
            else %means that i'm working on 1,3,5
                nSlider = nLabel - 1;
                site = 1;
            end
            currentSlider = sliders{nSlider};
            currentSlider.Limits(site) = app.axVals2(nSlider,site);
            
            currentSlider.Value = app.axVals(nSlider);
            currentSlider.MajorTicks(site) = app.axVals2(nSlider,site);
            
            labels = currentSlider.MajorTickLabels;
            for nLabel = 1:numel(labels)
                val = sprintf('%.2f',str2double(labels{nLabel}));
                currentSlider.MajorTickLabels{nLabel} = val;
            end
            
            
        end
        
        
        function profiles = calculateDisimilarity(app,colorList)
            %indxToTest = ~app.indxWithProfile(app.cutIndx);
            
            nPoints = size(app.transformedCloud.Color,1);
            profiles = zeros(nPoints,numel(colorList)); %will save the color profiles of each dot
            % getting color profile of each dot
            for nPoint = 1:nPoints
                disp([num2str(nPoint),' / ',num2str(nPoints)]);
                colorInPoint = double([app.transformedCloud.Color(nPoint,1),app.transformedCloud.Color(nPoint,2),app.transformedCloud.Color(nPoint,3)]);
                dissimilarity = colorProfile(colorInPoint,'colorList',colorList);
                profiles(nPoint,:) = dissimilarity;
            end
            
            
        end
        
        function [recolored,recoloredList] = recoloring(app,profiles,colorList)
            %generates new list of colors by replacing .Color with the
            %colors found in colorList. The colors are selected according
            %to the best match in profiles. Returns the new colors
            
            nPoints = size(app.transformedCloud.Color,1);
            recolored = zeros(nPoints,3);
            recoloredList = zeros(nPoints,1);
            for nPoint = 1:nPoints
                disp([num2str(nPoint),' / ',num2str(nPoints)]);
                
                [~,ix] = min(profiles(nPoint,:));
                newColor = colorList{ix}; %#ok<ADPROPLC> 
                recoloredList(nPoint) = ix;
                recolored(nPoint,1) = newColor(1); %#ok<ADPROPLC> 
                recolored(nPoint,2) = newColor(2); %#ok<ADPROPLC> 
                recolored(nPoint,3) = newColor(3); %#ok<ADPROPLC> 
            end
            
            
        end
        

        function app = plotCloud(app)
            ptCloudMod = pointCloud(app.transformedCloud.Location,'Color',app.transformedCloud.Color,'Normal',[]);
            
            if app.firstPlot
                figure(1)
                app.windowAx = gca;
              
            end
            pcshow(ptCloudMod,'Parent',app.windowAx, 'MarkerSize', app.dotSize);
            set(gca,'color','w');
            set(gcf,'color','w');
            xlabel(app.windowAx,'X');
            ylabel(app.windowAx,'Y');
            zlabel(app.windowAx,'Z');
            if app.firstPlot
                app.axVals = axis;        
                updateSliders(app);
                app.firstPlot = false;
            end
            
        end
        
         function plotRecoloredCloud(app)
            app.currentView = get(app.windowAx,'View');
            
            
            if isempty(app.newLocation)
                return
            end
            ptCloudMod = pointCloud(app.newLocation,'Color',app.newColor,'Normal',[]);
            
            pcshow(ptCloudMod,'Parent',app.windowAx, 'MarkerSize', app.dotSize);
            hold on
            axis(app.windowAx,[app.axVals(1),app.axVals(2),app.axVals(3),app.axVals(4),app.axVals(5),app.axVals(6)]);
            
            if size(app.newLocation,1) > 3
                %disp('plotting');
                nColors = app.nClusters * 3;
                
                %identifies possible clusters
                [~,app.centers] = kmeans(app.newLocation,nColors);
                ptCloudMod2 = pointCloud(app.centers,'Color',repmat([0,0,0],nColors,1),'Normal',[]); %drawing some black dots where the centers are estimated
                pcshow(ptCloudMod2,'Parent',app.windowAx, 'MarkerSize', app.clusterDot);
            end
            xlabel('X');
            ylabel('Y');
            zlabel('Z');
            set(gca,'color','w');
            set(gcf,'color','w');
            
            hold off
            axis(app.windowAx,[app.axVals(1),app.axVals(2),app.axVals(3),app.axVals(4),app.axVals(5),app.axVals(6)]);
            set(app.windowAx,'View',app.currentView);
        end
        
        function app = cutCloud(app)
            newLocation = app.originalCloud.Location;  %#ok<ADPROP> 
            newColor = app.originalCloud.Color; %#ok<ADPROP> 

            indx1 = logical(logical(newLocation(:,1) > app.axVals(1)) .* logical(newLocation(:,1) < app.axVals(2))); %#ok<ADPROP> %filtering X
            indx2 = logical(logical(newLocation(:,2) > app.axVals(3)) .* logical(newLocation(:,2) < app.axVals(4))); %#ok<ADPROP> %filtering Y
            indx3 = logical(logical(newLocation(:,3) > app.axVals(5)) .* logical(newLocation(:,3) < app.axVals(6))); %#ok<ADPROP> %filtering Z
            indx = logical(indx1.*indx2.*indx3);
            newLocation = newLocation(indx,:); %#ok<ADPROP> 
            newColor = newColor(indx,:);%#ok<ADPROP> 
            app.transformedCloud.Location = newLocation; %#ok<ADPROP> 
            app.transformedCloud.Color = newColor;%#ok<ADPROP> 
            app.cutIndx = indx;
        end
        
        function app = saveTile(app,selectedTile) %adds the tile to the saved tiles
            nTile = size(app.tiles,2) + 1; %adds one index for the new tile
            %this is to copy every field of the new tile
            props = fieldnames(app.currentTiles);
            for nProp = 1:numel(props)
                currentProp = props{nProp};
                app.tiles(nTile).(currentProp) = app.currentTiles(selectedTile).(currentProp);
            end
                        
            app.currentTiles(selectedTile) = [];
            if isfield(app.tiles(nTile),'ds')
                app.distances = [app.distances,app.tiles(nTile).ds];
            end
            %Use this to ignore distances in anatomical markers
            %if app.tiles(nTile).tileNumber < app.maxTileNumber - numel(app.anatomicalMarkers) || app.tiles(nTile).tileNumber > app.maxTileNumber %excludes anatomical markers (they might be bend)
            %    if isfield(app.tiles(nTile),'ds')
            %        app.distances = [app.distances,app.tiles(nTile).ds];
            %    end
            %end
        end
       
        
        function app = updateCurrentTiles(app)
            %plots a dot in the center and updates the text area
            if numel(app.currentTiles) > 0
                %app.CurrenttileSpinner.Enable = true;
                %app.CurrenttileSpinner.Limits = [1,numel(app.currentTiles)+1];
                
                %Showing the identified tile 1
                %app.CurrenttileSpinner.Value = 1;
                midpoint = app.currentTiles.midpoint;
                tileNumber = app.currentTiles.tileNumber;
                
                if tileNumber > app.maxTileNumber
                    app.TextArea.Value = 'Error, the number of tile detected is too big, change it';
                    %app.tileCorrectionDropDown.Visible = true;
                else
                    if tileNumber > app.maxTileNumber - numel(app.anatomicalMarkers) %marker is anatomic
                        markerN = tileNumber - (app.maxTileNumber - numel(app.anatomicalMarkers));
                        app.TextArea.Value = {['Marker: ', app.anatomicalMarkers{markerN}]};
                    else
                        app.TextArea.Value = {['Tile number: ', num2str(tileNumber)]};

                    end
                    
                end


                app = plotCloud(app);
                hold on
                ptCloudMod2 = pointCloud(midpoint,'Color',[0,0,0],'Normal',[]); %drawing some black dots where the centers are estimated
                pcshow(ptCloudMod2,'Parent',app.windowAx, 'MarkerSize', app.dotSize + 500);
                xlabel('X');
                ylabel('Y');
                zlabel('Z');
                set(gca,'color','w');
                set(gcf,'color','w');
                hold off
                shg
            else
                %app.CurrenttileSpinner.Enable = false;
            end
            
        end


        function app = plotnewTile(app)
            app.currentView = get(app.windowAx,'View');
            app = plotCloud(app);
            hold on
            for nDot = 1:numel(app.newTile.dotList)
                dotName = app.newTile.dotList{nDot};
                disp(dotName)
                color = app.newTile.colorList{nDot};
                x = app.newTile.(dotName)(1); y = app.newTile.(dotName)(2); z = app.newTile.(dotName)(3);
                ptCloudMod = pointCloud([x,y,z],'Color',color,'Normal',[]); %drawing some black dots where the centers are estimated
                pcshow(ptCloudMod,'Parent',app.windowAx, 'MarkerSize', app.dotSize+30);
            end
            xlabel('X');
            ylabel('Y');
            zlabel('Z');
            set(gca,'color','w');
            set(gcf,'color','w');
            hold off
            shg
            
            
            axis(app.windowAx,[app.axVals(1),app.axVals(2),app.axVals(3),app.axVals(4),app.axVals(5),app.axVals(6)]);
            set(app.windowAx,'View',app.currentView);
            
        end
        
        function app = resetArea(app)
            app.transformedCloud.Location = app.originalCloud.Location;
            app.transformedCloud.Color = app.originalCloud.Color;
            
            plotCloud(app);
            app.axVals = axis; %resets axis 
            updateSliders(app); %updates sliders           
            shg %change focus of figure
            
            app.saveTileButton.Enable = false;
            app.CustomtilePanel.Visible = false;
            app.tileCorrectionDropDown.Value = '-';
            app.whichDot.Value = 'select one';
            app.customDots.color1.coords = [];
            app.customDots.color2.coords = [];
            app.customDots.color3.coords = [];
            app.customDots.midpoint.coords = [];
        end
        
        function valueOut = convertMarker(app,value) %#ok<INUSL> 
            switch value     
                case '-'
                    valueOut = 0;
                case 'Nz'
                    valueOut = 13;
                case 'Iz'
                    valueOut = 14;
                case 'Ar'
                    valueOut = 15;
                case 'Al'
                    valueOut = 16;
                case 'Cz'
                    valueOut = 17;
                case 'Nose'
                    valueOut = 18;
                otherwise
                    valueOut = str2double(value);
            end
            
        end
         
        
        function plotDots(app,nDot)
            
            hold on
            linkdata on
            dotsPossible = {'color1','color2','color3','midpoint'};
            x = app.customDots.(dotsPossible{nDot}).coords(1);
            y = app.customDots.(dotsPossible{nDot}).coords(2);
            z = app.customDots.(dotsPossible{nDot}).coords(3);
            assignin('base', 'customDots', app.customDots); %updates in main, so the plot takes the values from there
            if nDot < 4
                color = app.colorList{nDot};
            else
                color = [0,0,0];
            end
            
            app.newTilePlot{nDot} = plot3(app.windowAx,x,y,z,'o','MarkerFaceColor',color,'MarkerEdgeColor',color); %,'MarkerSize',app.dotSize
            
            app.newTilePlot{nDot}.XDataSource = ['customDots.',dotsPossible{nDot},'.coords(1)'];
            app.newTilePlot{nDot}.YDataSource = ['customDots.',dotsPossible{nDot},'.coords(2)'];
            app.newTilePlot{nDot}.ZDataSource = ['customDots.',dotsPossible{nDot},'.coords(3)'];
            
            hold off
            app.TextArea.Value = 'Dot plotted';
            
            %enables save and tile selector
            app.saveTileButton.Enable = true;
            app.tileCorrectionDropDown.Visible = true;
            app.CustomtilePanel.Visible = true;
        end
        
        function updateSlidersDot(app)
             sliders = {'X_move','Y_move','Z_move'};
            for nSlider = 1:numel(sliders)
                slider = sliders{nSlider};
                app.(slider).Limits = [app.axVals(nSlider*2 - 1),app.axVals(nSlider*2)];
                app.(slider).Value = mean([app.axVals(nSlider*2 - 1),app.axVals(nSlider*2)]);
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.customColor.colorList = getColors(28); %initializes customColor
            addpath([pwd,'\functions']); %adds helper functions
            [file,path] = uigetfile('*.ply');
            if file == 0
                return
            end
            app.TextArea.Value = ['Loading ',file,', wait some seconds...'];
            %app.cloudFileName = file;
            %disp(file)ss
            try
                originalCloudRead = pcread([path,'\',file]);
            catch
                error('Select a file')
            end
            fileEnd = strfind(file,'.ply');
            app.matFile = [path,file(1:fileEnd),'mat'];
            if exist(app.matFile,'file')
                appProps = load(app.matFile);
                appProps = appProps.appProps;
                app.savedProps = appProps;
                app.originalCloud = appProps.originalCloud;
                if isfield(appProps,'tiles')
                    app.tiles = appProps.tiles;
                end
                if isfield(appProps,'cutoff')
                    app.cutoff = appProps.cutoff;
                end
            else
                app.originalCloud.Location = originalCloudRead.Location;
                app.originalCloud.Color = originalCloudRead.Color;
            end
            
            for nTile = 1:size(app.tiles,2)
                if app.tiles(nTile).tileNumber <= app.maxTileNumber - numel(app.anatomicalMarkers)
                    newTile = uitreenode(app.TilesNode,'Text',['Tile: ', num2str(app.tiles(nTile).tileNumber)]); %#ok<ADPROPLC>
                else
                    markerN = app.tiles(nTile).tileNumber - (app.maxTileNumber - numel(app.anatomicalMarkers));
                    newTile = uitreenode(app.TilesNode,'Text',['Tile: ', app.anatomicalMarkers{markerN}]); %#ok<ADPROPLC> 
                end
                newTile1 = uitreenode(newTile,'Text',['Center: ',num2str(app.tiles(nTile).midpoint(1)),', ', num2str(app.tiles(nTile).midpoint(2)),', ',num2str(app.tiles(nTile).midpoint(3))]); %#ok<ADPROPLC,NASGU> 
                expand(app.Tree3)
                
            end
             
            
            if ~isempty(app.cutoff)
                uitreenode(app.Tree3,'Text',['Cutoff : ',num2str(app.cutoff)])
                expand(app.Tree3);
                app.UsethresholdButton.Visible = false;
                app.SampledcolorsButton.Enable = true;
            end
            
            app.TextArea.Value = [file,' loaded'];
            
            %Initializing some variables 
            app.transformedCloud.Location = app.originalCloud.Location;
            app.transformedCloud.Color = app.originalCloud.Color;
            plotCloud(app);
            %Initializing some variables 
            app.customDots.color1.coords = [];
            app.customDots.color2.coords = [];
            app.customDots.color3.coords = [];
            app.customDots.midpoint.coords = [];
            
            shg
        end

        % Button pushed function: GetprofileButton
        function GetprofileButtonPushed(app, event)
            app.TextArea.Value = 'Calculating profile...';
            pause(0.01);
            app = cutCloud(app);
            app.profiles = calculateDisimilarity(app,app.colorList); %checks dissimilarity of points with the list of colors input
            originalColorList = getColors(28);
            [app.recolored,app.recoloredList] = recoloring(app,app.profiles,originalColorList);%recolors according to best match
            app.ThrSlider.Enable = true;
            app.ThrSlider.Value = 0.4;
            plotRecoloredCloud(app);
            app.TextArea.Value = 'Finished';
            %changeVisibility(app,2);
        end

        % Callback function: Xmax, Xmax, XmaxLabel, Xmin, Xmin, 
        % XminLabel, Ymax, Ymax, YmaxLabel, Ymin, Ymin, YminLabel, 
        % Zmax, Zmax, ZmaxLabel, Zmin, Zmin, ZminLabel
        function ValueChanged(app, event)
            changingValue = event.Value;
            eventName = event.Source.Tag;
            indx = strfind(eventName,'_');
            eventNumber = str2double(eventName(indx+1:end));
            updateLabels(app,eventNumber,changingValue);
            if app.ThrSlider.Enable
                app.ThrSlider.Enable = false;
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            propList = {'axVals','axVals2','originalCloud','transformedCloud',...
                'firstPlot','profiles','matFile','recolored','recoloredList','thr',...
                'centers', 'newLocation','cutoff', 'colorSample','colorList',...
                'recoloredList','currentTiles','eClusters', 'newColor','tiles','distances'};
            
            for nProp = 1:numel(propList)
                propName = propList{nProp};
                if ~isempty(app.(propName))
                    disp([propName, ' found']);
                    appProps.(propName) = app.(propName);
                end
            end
            [file,path] = uiputfile(app.matFile); %opens a dialog window suggesting a title for the mat file
            if file == 0
                app.TextArea.Value = 'Warning: no filename selected, file not saved';
            else
                save([path,file],'appProps');
                disp(['appProps saved in: ',[path,file]]);
            end
        end

        % Value changed function: ThrSlider
        function ThresSliderValueChanged(app, event)
            value = app.ThrSlider.Value;
            app.thr = value;
            colorEu = min(app.profiles,[],2);
            %max(colorEu)
            indx = colorEu < app.thr;
            app.newLocation = app.transformedCloud.Location(indx,:);
            app.colorSample = app.transformedCloud.Color(indx,:);
            app.newColor = app.recolored(indx,:);
            try
                app.newColorList = app.recoloredList(indx);
            catch
                size(app.newColor)
                size(app.newColorList)
                error('Error in newColor')
            end
            plotRecoloredCloud(app);
            
            %disp('draw')
        end

        % Button pushed function: UsethresholdButton
        function UsethresholdButtonPushed(app, event)
            distances = pdist(app.centers,'euclidean'); %#ok<ADPROPLC> %calculates the distances between cluster centers
            app.cutoff = mean(distances)*0.5; %#ok<ADPROPLC> %assignates a cutoff threshold
            
            %This is to test wheter the cutoff is correct
            Y = pdist(app.newLocation,'euclidean'); 
            Z = linkage(Y);
            T = cluster(Z,'cutoff',app.cutoff, 'Criterion','distance');
            
            if length(unique(T)) == 3 %checks if the cutoff is correct
                disp('Well done')
                disp(['cutoff = ', num2str(app.cutoff)]);
                app.TextArea.Value = 'correct number of clusters, cutoff saved';
                app.UsethresholdButton.Visible = false;
                uitreenode(app.Tree3,'Text',['Cutoff : ',num2str(app.cutoff)]);
                expand(app.Tree3);
            else
                app.TextArea.Value = [num2str(length(unique(T))),' clusters found, it must be 3, try again'];
            end
            
            %assign colors sampled in clusters to sampledColors
            nColors = 3; 
            app.sampledColor = cell(1,nColors);
            for nColor = 1:nColors
                indx = T == nColor;
                singleColor = app.colorSample(indx,:);
                app.sampledColor{nColor} = mean(singleColor)/255;
            end
            app.SampledcolorsButton.Enable = true;
            
            %changeVisibility(app,3);
        end

        % Button pushed function: findCenterButton1
        function findCenterButton1Pushed(app, event)
            minimalSize = app.MinimalclustersizeSpinner.Value; %minimal number of dots so the cluster is considered
  
            
            app.eClusters = calculateClusters(app.newLocation,app.newColorList,app.cutoff,minimalSize);
            if isempty(app.eClusters)
                app.TextArea.Value = 'Error, no tile found. Try changing the minimal cluster size';
            else
                app.currentTiles = findTiles(app.eClusters,1,app.originalCloud); %finds clusters according to their distance
                updateCurrentTiles(app); %updates text area and plots black dot
                
                %enable save option and correction
                app.saveTileButton.Enable = true;
                app.tileCorrectionDropDown.Visible = true;
                
            end


        end

        % Button pushed function: saveTileButton
        function saveTileButtonButtonPushed(app, event)
            selectedTile = 1;%app.CurrenttileSpinner.Value;
            app = saveTile(app,selectedTile); %saves the info about the tile in the structure tiles
            
            %updateCurrentTiles(app); % updates spinner and plots a black dot
            
            if ~strcmp(app.tileCorrectionDropDown.Value,'-')
                value = convertMarker(app,app.tileCorrectionDropDown.Value);
                app.tiles(end).tileNumber = value;
            end
            
            
            %adding tile info to tree
            if app.tiles(end).tileNumber <= app.maxTileNumber - numel(app.anatomicalMarkers)
                newTile = uitreenode(app.TilesNode,'Text',['Tile: ', num2str(app.tiles(end).tileNumber)]); %#ok<ADPROPLC> 
            else
                markerN = app.tiles(end).tileNumber - (app.maxTileNumber - numel(app.anatomicalMarkers));
                newTile = uitreenode(app.TilesNode,'Text',['Tile: ', app.anatomicalMarkers{markerN}]); %#ok<ADPROPLC> 
            end
            newTile1 = uitreenode(newTile,'Text',['Center: ',num2str(app.tiles(end).midpoint(1)),', ', num2str(app.tiles(end).midpoint(2)),', ',num2str(app.tiles(end).midpoint(3))]); %#ok<ADPROPLC,NASGU> 
            expand(app.Tree3)
            
            %removing dots from cloud
            indx = checkWithinTile(app.tiles,size(app.tiles,2),app.originalCloud.Location);
            app.originalCloud.Location(indx,:) = [];
            app.originalCloud.Color(indx,:) = [];
            
            app.TextArea.Value = 'Tile saved';
            %Resetting variables
            app = resetArea(app); 
       
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.TextArea.Value = 'Reset';
            app = resetArea(app); %#ok<NASGU> 
        end

        % Button pushed function: HardcutButton
        function HardcutButtonPushed(app, event)
            app = cutCloud(app);
            app.originalCloud.Location = app.transformedCloud.Location;
            app.originalCloud.Color = app.transformedCloud.Color;
            app.TextArea.Value = 'Hard cut applied';
            app = resetArea(app); %#ok<NASGU> 
        end

        % Value changed function: CutoffEditField
        function CutoffEditFieldValueChanged(app, event)
            value = app.CutoffEditField.Value;
            app.cutoff = value;
        end

        % Button pushed function: PrintButton
        function PrintButtonPushed(app, event)
            %size(app.transformedCloud.Location)
            %num2str(app.tiles(end).midpoint(1))%,', ', num2str(app.tiles(end).midpoint(2)),', ',num2str(app.tiles(end).midpoint(3))]
%             app.tiles(end)
            %app.tiles(end)
            %assignin('base', 'colorInCloud', app.colorInCloud); %updates in main, so the plot takes the values from there
            %assignin('base','transformedCloud',app.transformedCloud)
            %app.newTile
%             strList = strsplit(app.matFile,'\');
%             strList{end};
            
            %app.transformedCloud.Color
           %app.colorList
           %app.sampledColor
%            app.customColor.color
%            app.customColor.colorN
%             disp('app.colorList')
%             app.colorList
%             disp('app.customColor.colorList')
%             app.customColor.colorList
            %colorList
            %transformedCloud
            %app.customColor
            %[app.tiles.tileNumber]
            %size(app.newLocation)
            %size(app.newColor)
        end

        % Selection changed function: WhichcolortouseButtonGroup
        function WhichcolortouseButtonGroupSelectionChanged(app, event)
            selectedButton = app.WhichcolortouseButtonGroup.SelectedObject;
            switch selectedButton.Tag
                case 'saved' %use ideal colors
                    app.colorList = getColors(28);
                    app.CustomcolorPanel.Visible = false;
                case 'sampled' %use sampled colors
                    app.colorList = app.sampledColor;
                case 'custom'
                    app.CustomcolorPanel.Visible = true;
            end
        end

        % Button pushed function: PlotoriginalButton
        function PlotoriginalButtonPushed(app, event)
            app.currentView = get(app.windowAx,'View');
            plotCloud(app);
            axis(app.windowAx,[app.axVals(1),app.axVals(2),app.axVals(3),app.axVals(4),app.axVals(5),app.axVals(6)]);
            set(app.windowAx,'View',app.currentView);
        end

        % Button pushed function: PlotrecoloredButton
        function PlotrecoloredButtonPushed(app, event)
            app.currentView = get(app.windowAx,'View');
            plotRecoloredCloud(app);
            axis(app.windowAx,[app.axVals(1),app.axVals(2),app.axVals(3),app.axVals(4),app.axVals(5),app.axVals(6)]);
            set(app.windowAx,'View',app.currentView);
        end

        % Value changed function: DotsizeSpinner
        function DotsizeSpinnerValueChanged(app, event)
            value = app.DotsizeSpinner.Value;
            app.dotSize = value;
            app.clusterDot = value + 80;
        end

        % Callback function: X_move, X_move, Y_move, Y_move, Z_move, 
        % Z_move
        function movingDotControl(app, event)
            identifiers = {'xt','yt','zt'}; %identifiers of the controls
            
            %checks which element was changed and updates. This part is to
            %update in real time
            changedElement = event.Source.Tag;
            changedValue = event.Value;
            indx = strcmp(identifiers,changedElement);
            dotName = app.whichDot.Value;
            app.customDots.(dotName).coords(indx) = changedValue;
            assignin('base', 'customDots', app.customDots); %updates in main, so the plot takes the values from there
            %app = assignLineCoords(app); %assigns the coordinates to x,y,zList
            %assignin('base', 'newTileLines',app.newTileLines);
            %app.currentTiles = app.newTile;
        end

        % Value changed function: tileCorrectionDropDown
        function tileCorrectionDropDownValueChanged(app, event)
            value = app.tileCorrectionDropDown.Value;
            value = convertMarker(app,value);

            if value ~= 0 %if different from default, force the change into currentTiles
                app.currentTiles.tileNumber = value;
            end
            disp(app.currentTiles.tileNumber)
        end

        % Value changed function: CyanEditField, MagentaEditField, 
        % YellowEditField
        function CustomColorFieldValueChanged(app, event)
            value = event.Source.Value;
            eventName = event.Source.Tag;
            colorN = str2double(eventName(7));
            app.customColor.color = str2double(strsplit(value,','))./255;
            app.customColor.colorN = colorN;
            %app.YellowEditField.Enable
        end

        % Value changing function: CyanEditField, MagentaEditField, 
        % YellowEditField
        function CustomColorFieldValueChanging(app, event)
            colorFields = {'MagentaEditField','CyanEditField','YellowEditField'};
            app.customColorApplyButton.Enable = true;
            eventName = event.Source.Tag;
            colorN = str2double(eventName(7));
            notSelected = find(colorN ~= 1:3);
            for n = notSelected
                colorField = colorFields{n};
                %disp(colorField)
                app.(colorField).Enable = false;
            end
        end

        % Button pushed function: customColorApplyButton
        function customColorApplyButtonPushed(app, event)
            colorFields = {'MagentaEditField','CyanEditField','YellowEditField'};
            app.customColor.colorList{app.customColor.colorN} = app.customColor.color;
            %app.customColor.color{app.customColor.colorN} = 
            %app.colorList{app.customColor.colorN} = app.customColor.color;
            
            app.colorList = app.customColor.colorList;
            
            for n = 1:numel(colorFields)
                colorField = colorFields{n};
                app.(colorField).Enable = true;
                app.(colorField).Value = '';
            end
            %app.customColorApplyButton.Enable = false;
            app.RecolorButton.Enable = true;
            app.TextArea.Value = 'Custom color saved';
        end

        % Button pushed function: RecolorButton
        function RecolorButtonPushed(app, event)
            originalColor = getColors(28);
            measuredColor = app.colorList;
            recoloredCloud = recolorCloud(app.transformedCloud.Color,measuredColor,originalColor);
            app.transformedCloud.Color = recoloredCloud;            
            app.TextArea.Value = 'Recolored';
            %app.originalCloud
        end

        % Value changed function: whichDot
        function whichDotValueChanged(app, event)
            dotName = app.whichDot.Value;
            dotsPossible = {'color1','color2','color3','midpoint','select one'};
            
            nDot = find(strcmp(dotName,dotsPossible));
            if nDot > 4
                disp('returned')
                return
            end
            x = []; y=[]; z=[];
            if isempty(app.customDots.(dotName).coords) %check if it has previous coordinates
                
                for n = 1:numel(dotsPossible)-1 %check every other dot and copy the first one available
                    tmpDot = dotsPossible{n};
                    
                    if ~isempty(app.customDots.(tmpDot).coords) %one found, assigning x,y,z
                        x = app.customDots.(tmpDot).coords(1);
                        y = app.customDots.(tmpDot).coords(2);
                        z = app.customDots.(tmpDot).coords(3);
                        break
                    end
                end
                if isempty(x) %no previous dot found, putting one in the center
                    x = mean([app.axVals(1),app.axVals(2)]);
                    y = mean([app.axVals(3),app.axVals(4)]);
                    z = mean([app.axVals(5),app.axVals(6)]);
                end
                app.customDots.(dotName).coords = [x,y,z];
            end
            
            sliders = {'X_move','Y_move','Z_move'};
            for nSlider = 1:numel(sliders)
                slider = sliders{nSlider};
                app.(slider).Value = app.customDots.(dotName).coords(nSlider);
                app.(slider).Enable = true;
            end
            plotDots(app,nDot);
            if ~isempty(app.customDots.color1.coords) && ~isempty(app.customDots.color2.coords) && ~isempty(app.customDots.color3.coords)
                app.findCenterButton2.Enable = true;
            
                
            end
            
            
        end

        % Button pushed function: createtileButton
        function createtileButtonPushed(app, event)
            app.CustomtilePanel.Visible = true;
            app.tileCorrectionDropDown.Enable = false;
            %update slider limit and values
            updateSlidersDot(app);
        end

        % Button pushed function: findCenterButton2
        function findCenterButton2Pushed(app, event)
            
            %checks coordinates of the three dots and the center
            app.newTile = createCustomTile(app.distances); %creates tile
            %disp('new tile created')
            coords = zeros(1,3);
            for nCoord = 1:3 %calculates midpoint and assigns the coordinates of other dots to newTile
                coords(nCoord) = mean([app.customDots.color1.coords(nCoord),app.customDots.color2.coords(nCoord),app.customDots.color3.coords(nCoord)]);
                app.newTile.color1(nCoord) = app.customDots.color1.coords(nCoord);
                app.newTile.color2(nCoord) = app.customDots.color2.coords(nCoord);
                app.newTile.color3(nCoord) = app.customDots.color3.coords(nCoord);
            end
            
            app.customDots.midpoint.coords = coords; %assigns the center
            app.newTile.midpoint = coords; %assings center
            app.whichDot.Value = 'midpoint'; %updates dropdown menu
            updateSlidersDot(app); %updateSliders
            plotDots(app,4); %plots new dot
            app.tileCorrectionDropDown.Enable = true;
            app.currentTiles = app.newTile; %assigns the newTile to currentTiles
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.LoadButton = uibutton(app.Panel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.Xmax = uislider(app.Panel);
            app.Xmax.MajorTicks = [0 100];
            app.Xmax.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.Xmax.ValueChangingFcn = createCallbackFcn(app, @ValueChanged, true);
            app.Xmax.MinorTicks = [0 100];
            app.Xmax.Tag = 'slider_02';
            app.Ymax = uislider(app.Panel);
            app.Ymax.MajorTicks = [0 100];
            app.Ymax.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.Ymax.ValueChangingFcn = createCallbackFcn(app, @ValueChanged, true);
            app.Ymax.Tag = 'slider_04';
            app.Zmax = uislider(app.Panel);
            app.Zmax.MajorTicks = [0 100];
            app.Zmax.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.Zmax.ValueChangingFcn = createCallbackFcn(app, @ValueChanged, true);
            app.Zmax.Tag = 'slider_06';
            app.LowerlimitLabel = uilabel(app.Panel);
            app.LowerlimitLabel.Text = 'Lower limit';
            app.UpperlimitLabel = uilabel(app.Panel);
            app.UpperlimitLabel.Text = 'Upper limit';
            app.GetprofileButton = uibutton(app.Panel, 'push');
            app.GetprofileButton.ButtonPushedFcn = createCallbackFcn(app, @GetprofileButtonPushed, true);
            app.GetprofileButton.Tag = 'next_01';
            app.GetprofileButton.Text = 'Get profile';
            app.XSlider_7Label = uilabel(app.Panel);
            app.XSlider_7Label.Text = 'X';
            app.Xmin = uislider(app.Panel);
            app.Xmin.MajorTicks = [0 100];
            app.Xmin.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.Xmin.ValueChangingFcn = createCallbackFcn(app, @ValueChanged, true);
            app.Xmin.MinorTicks = [0 100];
            app.Xmin.Tag = 'slider_01';
            app.YSliderLabel = uilabel(app.Panel);
            app.YSliderLabel.Text = 'Y';
            app.Ymin = uislider(app.Panel);
            app.Ymin.MajorTicks = [0 100];
            app.Ymin.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.Ymin.ValueChangingFcn = createCallbackFcn(app, @ValueChanged, true);
            app.Ymin.Tag = 'slider_03';
            app.ZSliderLabel = uilabel(app.Panel);
            app.ZSliderLabel.Text = 'Z';
            app.Zmin = uislider(app.Panel);
            app.Zmin.MajorTicks = [0 100];
            app.Zmin.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.Zmin.ValueChangingFcn = createCallbackFcn(app, @ValueChanged, true);
            app.Zmin.Tag = 'slider_05';
            app.XminLabel = uieditfield(app.Panel, 'numeric');
            app.XminLabel.ValueDisplayFormat = '%.2f';
            app.XminLabel.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.XminLabel.Tag = 'label_01';
            app.XmaxLabel = uieditfield(app.Panel, 'numeric');
            app.XmaxLabel.ValueDisplayFormat = '%.2f';
            app.XmaxLabel.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.XmaxLabel.Tag = 'label_02';
            app.YminLabel = uieditfield(app.Panel, 'numeric');
            app.YminLabel.ValueDisplayFormat = '%.2f';
            app.YminLabel.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.YminLabel.Tag = 'label_03';
            app.ZminLabel = uieditfield(app.Panel, 'numeric');
            app.ZminLabel.ValueDisplayFormat = '%.2f';
            app.ZminLabel.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.ZminLabel.Tag = 'label_05';
            app.YmaxLabel = uieditfield(app.Panel, 'numeric');
            app.YmaxLabel.ValueDisplayFormat = '%.2f';
            app.YmaxLabel.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.YmaxLabel.Tag = 'label_04';
            app.ZmaxLabel = uieditfield(app.Panel, 'numeric');
            app.ZmaxLabel.ValueDisplayFormat = '%.2f';
            app.ZmaxLabel.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.ZmaxLabel.Tag = 'label_06';
            app.SaveButton = uibutton(app.Panel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ThrSlider = uislider(app.Panel);
            app.ThrSlider.Limits = [0 1];
            app.ThrSlider.MajorTicks = [0.1 0.4 0.7 1];
            app.ThrSlider.MajorTickLabels = {'0.1', '0.4', '0.7', '1'};
            app.ThrSlider.ValueChangedFcn = createCallbackFcn(app, @ThresSliderValueChanged, true);
            app.ThrSlider.MinorTicks = [0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.ThrSlider.Value = 0.1;
            app.ColorsimilaritythresholdLabel = uilabel(app.Panel);
            app.ColorsimilaritythresholdLabel.Text = 'Color similarity threshold';
            app.UsethresholdButton = uibutton(app.Panel, 'push');
            app.UsethresholdButton.ButtonPushedFcn = createCallbackFcn(app, @UsethresholdButtonPushed, true);
            app.UsethresholdButton.Tag = 'next_02';
            app.UsethresholdButton.Text = 'Use threshold';
            app.ResetButton = uibutton(app.Panel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.HardcutButton = uibutton(app.Panel, 'push');
            app.HardcutButton.ButtonPushedFcn = createCallbackFcn(app, @HardcutButtonPushed, true);
            app.HardcutButton.Text = 'Hard cut';
            app.PrintButton = uibutton(app.Panel, 'push');
            app.PrintButton.ButtonPushedFcn = createCallbackFcn(app, @PrintButtonPushed, true);
            app.PrintButton.Text = 'Print';
            app.PlotoriginalButton = uibutton(app.Panel, 'push');
            app.PlotoriginalButton.ButtonPushedFcn = createCallbackFcn(app, @PlotoriginalButtonPushed, true);
            app.PlotoriginalButton.Text = 'Plot original';
            app.PlotrecoloredButton = uibutton(app.Panel, 'push');
            app.PlotrecoloredButton.ButtonPushedFcn = createCallbackFcn(app, @PlotrecoloredButtonPushed, true);
            app.PlotrecoloredButton.Text = 'Plot recolored';
            app.DotsizeSpinnerLabel = uilabel(app.Panel);
            app.DotsizeSpinnerLabel.Text = 'Dot size';
            app.DotsizeSpinner = uispinner(app.Panel);
            app.DotsizeSpinner.Limits = [1 Inf];
            app.DotsizeSpinner.ValueChangedFcn = createCallbackFcn(app, @DotsizeSpinnerValueChanged, true);
            app.DotsizeSpinner.Value = 10;
            app.MinimalclustersizeSpinnerLabel = uilabel(app.Panel);
            app.MinimalclustersizeSpinnerLabel.Text = 'Minimal cluster size';
            app.MinimalclustersizeSpinner = uispinner(app.Panel);
            app.MinimalclustersizeSpinner.Limits = [1 Inf];
            app.MinimalclustersizeSpinner.RoundFractionalValues = 'on';
            app.MinimalclustersizeSpinner.Value = 2;
            app.findCenterButton1 = uibutton(app.Panel, 'push');
            app.findCenterButton1.ButtonPushedFcn = createCallbackFcn(app, @findCenterButton1Pushed, true);
            app.findCenterButton1.Text = 'Find centers';
            app.WhichcolortouseButtonGroup = uibuttongroup(app.Panel);
            app.WhichcolortouseButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WhichcolortouseButtonGroupSelectionChanged, true);
            app.WhichcolortouseButtonGroup.Title = 'Which color to use?';
            app.SavedcolorsButton = uiradiobutton(app.WhichcolortouseButtonGroup);
            app.SavedcolorsButton.Tag = 'saved';
            app.SavedcolorsButton.Text = 'Saved colors';
            app.SavedcolorsButton.Value = true;
            app.SampledcolorsButton = uiradiobutton(app.WhichcolortouseButtonGroup);
            app.SampledcolorsButton.Tag = 'sampled';
            app.SampledcolorsButton.Enable = 'off';
            app.SampledcolorsButton.Text = 'Sampled colors';
            app.CustomcolorButton = uiradiobutton(app.WhichcolortouseButtonGroup);
            app.CustomcolorButton.Tag = 'custom';
            app.CustomcolorButton.Text = 'Custom color';
            app.RecolorButton = uibutton(app.Panel, 'push');
            app.RecolorButton.ButtonPushedFcn = createCallbackFcn(app, @RecolorButtonPushed, true);
            app.RecolorButton.Enable = 'off';
            app.RecolorButton.Text = 'Recolor';
            app.TextArea = uitextarea(app.UIFigure);
            app.saveTileButton = uibutton(app.UIFigure, 'push');
            app.saveTileButton.ButtonPushedFcn = createCallbackFcn(app, @saveTileButtonButtonPushed, true);
            app.saveTileButton.Enable = 'off';
            app.saveTileButton.Text = 'Save tile';
            app.CutoffEditFieldLabel = uilabel(app.UIFigure);
            app.CutoffEditFieldLabel.Text = 'Cutoff';
            app.CutoffEditField = uieditfield(app.UIFigure, 'numeric');
            app.CutoffEditField.ValueChangedFcn = createCallbackFcn(app, @CutoffEditFieldValueChanged, true);
            app.Tree3 = uitree(app.UIFigure);
            app.TilesNode = uitreenode(app.Tree3);
            app.TilesNode.Text = 'Tiles';
            app.createtileButton = uibutton(app.UIFigure, 'push');
            app.createtileButton.ButtonPushedFcn = createCallbackFcn(app, @createtileButtonPushed, true);
            app.createtileButton.Text = 'create tile';
            app.TileDropDown_2Label = uilabel(app.UIFigure);
            app.TileDropDown_2Label.Visible = 'off';
            app.TileDropDown_2Label.Text = 'Tile';
            app.tileCorrectionDropDown = uidropdown(app.UIFigure);
            app.tileCorrectionDropDown.Items = {'-', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', 'Nz', 'Iz', 'Ar', 'Al', 'Cz', 'Nose'};
            app.tileCorrectionDropDown.Editable = 'on';
            app.tileCorrectionDropDown.ValueChangedFcn = createCallbackFcn(app, @tileCorrectionDropDownValueChanged, true);
            app.tileCorrectionDropDown.Visible = 'off';
            app.tileCorrectionDropDown.Value = '-';
            app.CustomtilePanel = uipanel(app.UIFigure);
            app.CustomtilePanel.Title = 'Custom tile';
            app.CustomtilePanel.Visible = 'off';
            app.XSlider_7Label_2 = uilabel(app.CustomtilePanel);
            app.XSlider_7Label_2.Text = 'X';
            app.X_move = uislider(app.CustomtilePanel);
            app.X_move.Limits = [-10 10];
            app.X_move.MajorTicks = [-10 10];
            app.X_move.MajorTickLabels = {''};
            app.X_move.ValueChangedFcn = createCallbackFcn(app, @movingDotControl, true);
            app.X_move.ValueChangingFcn = createCallbackFcn(app, @movingDotControl, true);
            app.X_move.MinorTicks = [-10 10];
            app.X_move.Tag = 'xt';
            app.X_move.Enable = 'off';
            app.YSliderLabel_2 = uilabel(app.CustomtilePanel);
            app.YSliderLabel_2.Text = 'Y';
            app.Y_move = uislider(app.CustomtilePanel);
            app.Y_move.Limits = [-10 10];
            app.Y_move.MajorTicks = [-10 10];
            app.Y_move.MajorTickLabels = {''};
            app.Y_move.ValueChangedFcn = createCallbackFcn(app, @movingDotControl, true);
            app.Y_move.ValueChangingFcn = createCallbackFcn(app, @movingDotControl, true);
            app.Y_move.Tag = 'yt';
            app.Y_move.Enable = 'off';
            app.ZSliderLabel_2 = uilabel(app.CustomtilePanel);
            app.ZSliderLabel_2.Text = 'Z';
            app.Z_move = uislider(app.CustomtilePanel);
            app.Z_move.Limits = [-10 10];
            app.Z_move.MajorTicks = [-10 10];
            app.Z_move.MajorTickLabels = {''};
            app.Z_move.ValueChangedFcn = createCallbackFcn(app, @movingDotControl, true);
            app.Z_move.ValueChangingFcn = createCallbackFcn(app, @movingDotControl, true);
            app.Z_move.Tag = 'zt';
            app.Z_move.Enable = 'off';
            app.DotDropDownLabel = uilabel(app.CustomtilePanel);
            app.DotDropDownLabel.Text = 'Dot';
            app.whichDot = uidropdown(app.CustomtilePanel);
            app.whichDot.Items = {'select one', 'color1', 'color2', 'color3', 'midpoint'};
            app.whichDot.ValueChangedFcn = createCallbackFcn(app, @whichDotValueChanged, true);
            app.whichDot.Value = 'select one';
            app.findCenterButton2 = uibutton(app.CustomtilePanel, 'push');
            app.findCenterButton2.ButtonPushedFcn = createCallbackFcn(app, @findCenterButton2Pushed, true);
            app.findCenterButton2.Enable = 'off';
            app.findCenterButton2.Text = 'Find center';
            app.CustomcolorPanel = uipanel(app.UIFigure);
            app.CustomcolorPanel.Title = 'Custom color';
            app.CustomcolorPanel.Visible = 'off';
            app.MagentaEditFieldLabel = uilabel(app.CustomcolorPanel);
            app.MagentaEditFieldLabel.Text = 'Magenta';
            app.MagentaEditField = uieditfield(app.CustomcolorPanel, 'text');
            app.MagentaEditField.ValueChangedFcn = createCallbackFcn(app, @CustomColorFieldValueChanged, true);
            app.MagentaEditField.ValueChangingFcn = createCallbackFcn(app, @CustomColorFieldValueChanging, true);
            app.MagentaEditField.Tag = 'custom1';
            app.CyanEditFieldLabel = uilabel(app.CustomcolorPanel);
            app.CyanEditFieldLabel.Text = 'Cyan';
            app.CyanEditField = uieditfield(app.CustomcolorPanel, 'text');
            app.CyanEditField.ValueChangedFcn = createCallbackFcn(app, @CustomColorFieldValueChanged, true);
            app.CyanEditField.ValueChangingFcn = createCallbackFcn(app, @CustomColorFieldValueChanging, true);
            app.CyanEditField.Tag = 'custom2';
            app.YellowEditFieldLabel = uilabel(app.CustomcolorPanel);
            app.YellowEditFieldLabel.Text = 'Yellow';
            app.YellowEditField = uieditfield(app.CustomcolorPanel, 'text');
            app.YellowEditField.ValueChangedFcn = createCallbackFcn(app, @CustomColorFieldValueChanged, true);
            app.YellowEditField.ValueChangingFcn = createCallbackFcn(app, @CustomColorFieldValueChanging, true);
            app.YellowEditField.Tag = 'custom3';
            app.customColorApplyButton = uibutton(app.CustomcolorPanel, 'push');
            app.customColorApplyButton.ButtonPushedFcn = createCallbackFcn(app, @customColorApplyButtonPushed, true);
            app.customColorApplyButton.Enable = 'off';
            app.customColorApplyButton.Text = 'Apply';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_3DLocator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end